classdef psathmi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        voltageC01BValue           matlab.ui.control.Label
        voltageC01BUnit            matlab.ui.control.Label
        frequencyC01BValue         matlab.ui.control.Label
        frequencyC01BUnit          matlab.ui.control.Label
        voltageC01AValue           matlab.ui.control.Label
        voltageC01AUnit            matlab.ui.control.Label
        frequencyC01AValue         matlab.ui.control.Label
        frequencyC01AUnit          matlab.ui.control.Label
        powerTrAc01BValue          matlab.ui.control.Label
        powerTrAc01BUnit           matlab.ui.control.Label
        reactiveTrAc01BValue       matlab.ui.control.Label
        reactiveTrAc01BUnit        matlab.ui.control.Label
        currentTrAc01BValue        matlab.ui.control.Label
        currentTrAc01BUnit         matlab.ui.control.Label
        voltageTrAc01BValue        matlab.ui.control.Label
        voltagetTrAc01BUnit        matlab.ui.control.Label
        powerTrAc01AValue          matlab.ui.control.Label
        powerTrAc01AUnit           matlab.ui.control.Label
        reactiveTrAc01AValue       matlab.ui.control.Label
        reactiveTrAc01AUnit        matlab.ui.control.Label
        currentTrAc01AValue        matlab.ui.control.Label
        currentTrAc01AUnit         matlab.ui.control.Label
        voltageTrAc01AValue        matlab.ui.control.Label
        voltagetTrAc01AUnit        matlab.ui.control.Label
        frequencyGt01Value         matlab.ui.control.Label
        voltageGt01Unit            matlab.ui.control.Label
        voltageGt01Value           matlab.ui.control.Label
        currentGt01Unit            matlab.ui.control.Label
        currentGt01Value           matlab.ui.control.Label
        activeGt01Unit             matlab.ui.control.Label
        activeGt01Value            matlab.ui.control.Label
        reactiveGt01Unit           matlab.ui.control.Label
        reactiveGt01Value          matlab.ui.control.Label
        powerGt01Unit              matlab.ui.control.Label
        powerGt01Value             matlab.ui.control.Label
        frequencyGt01Unit          matlab.ui.control.Label
        powerSg03Value             matlab.ui.control.Label
        powerSg03Unit              matlab.ui.control.Label
        reactiveSg03Value          matlab.ui.control.Label
        reactiveSg03Unit           matlab.ui.control.Label
        activeSg03Value            matlab.ui.control.Label
        activeSg03Unit             matlab.ui.control.Label
        currentSg03Value           matlab.ui.control.Label
        currentSg03Unit            matlab.ui.control.Label
        voltageSg03Value           matlab.ui.control.Label
        voltageSg03Unit            matlab.ui.control.Label
        frequencySg03Value         matlab.ui.control.Label
        frequencySg03Unit          matlab.ui.control.Label
        frequencySg02Unit          matlab.ui.control.Label
        frequencySg02Value         matlab.ui.control.Label
        voltageSg02Unit            matlab.ui.control.Label
        voltageSg02Value           matlab.ui.control.Label
        currentSg02Unit            matlab.ui.control.Label
        currentSg02Value           matlab.ui.control.Label
        activeSg02Unit             matlab.ui.control.Label
        activeSg02Value            matlab.ui.control.Label
        reactiveSg02Unit           matlab.ui.control.Label
        reactiveSg02Value          matlab.ui.control.Label
        powerSg02Unit              matlab.ui.control.Label
        powerSg02Value             matlab.ui.control.Label
        powerSg01Value             matlab.ui.control.Label
        powerSg01Unit              matlab.ui.control.Label
        reactiveSg01Value          matlab.ui.control.Label
        reactiveSg01Unit           matlab.ui.control.Label
        activeSg01Value            matlab.ui.control.Label
        activeSg01Unit             matlab.ui.control.Label
        currentSg01Value           matlab.ui.control.Label
        currentSg01Unit            matlab.ui.control.Label
        voltageSg01Value           matlab.ui.control.Label
        voltageSg01Unit            matlab.ui.control.Label
        frequencySg01Value         matlab.ui.control.Label
        frequencySg01Unit          matlab.ui.control.Label
        condition_1_label          matlab.ui.control.Label
        condition_2_label          matlab.ui.control.Label
        condition_3_label          matlab.ui.control.Label
        ForceConditionButton       matlab.ui.control.Button
        SimulationOnLineButton     matlab.ui.control.Button
        OPCButton                  matlab.ui.control.Button
        StartPowerFlowButton       matlab.ui.control.Button
        Sg01Img                    matlab.ui.control.Image
        Sg02Img                    matlab.ui.control.Image
        Sg03Img                    matlab.ui.control.Image
        Sg04Img                    matlab.ui.control.Image
        TrafoSg01Img               matlab.ui.control.Image
        TrafoSg02Img               matlab.ui.control.Image
        TrafoSg03Img               matlab.ui.control.Image
        TrafoSg04Img               matlab.ui.control.Image
        SwitchSg01Img              matlab.ui.control.Image
        SwitchSg02Img              matlab.ui.control.Image
        SwitchSg03Img              matlab.ui.control.Image
        SwitchSg04Img              matlab.ui.control.Image
        BusA02Img                  matlab.ui.control.Image
        BusA03Img                  matlab.ui.control.Image
        SwitchSTAImg               matlab.ui.control.Image
        SwitchSTBImg               matlab.ui.control.Image
        TrafoAC01AImg              matlab.ui.control.Image
        TrafoAC01BImg              matlab.ui.control.Image
        SwitchAC01AImg             matlab.ui.control.Image
        SwitchAC01BImg             matlab.ui.control.Image
        BusC01AImg                 matlab.ui.control.Image
        BusC01BImg                 matlab.ui.control.Image
        SwitchSMAImg               matlab.ui.control.Image
        SwitchSMBImg               matlab.ui.control.Image
        SwitchSMCImg               matlab.ui.control.Image
        SwitchSMDImg               matlab.ui.control.Image
        k1Img                      matlab.ui.control.Image
        k2Img                      matlab.ui.control.Image
        k3Img                      matlab.ui.control.Image
        k4Img                      matlab.ui.control.Image
        SwitchC01Img               matlab.ui.control.Image
        StartStopSG01Button        matlab.ui.control.Button
        StartStopSG02Button        matlab.ui.control.Button
        StartStopSG03Button        matlab.ui.control.Button
        StartStopGT01Button        matlab.ui.control.Button
        OpenCloseSwitchSG01Button  matlab.ui.control.Button
        OpenCloseSwitchSG02Button  matlab.ui.control.Button
        OpenCloseSwitchSG03Button  matlab.ui.control.Button
        OpenCloseSwitchGT01Button  matlab.ui.control.Button
        OpenCloseSTAButton         matlab.ui.control.Button
        OpenCloseSTBButton         matlab.ui.control.Button
        OpenCloseSwAC01BButton     matlab.ui.control.Button
        OpenCloseSwAC01AButton     matlab.ui.control.Button
        OpenCloseSMAButton         matlab.ui.control.Button
        OpenCloseSMBButton         matlab.ui.control.Button
        OpenCloseSMCButton         matlab.ui.control.Button
        OpenCloseSMDButton         matlab.ui.control.Button
        OpenCloseSwitchC01Button   matlab.ui.control.Button
        Background                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        %variable
        Sg01Status = 0;
        TrafoSg01Status = 0;
        SwitchSg01Status = 0;
        Sg02Status = 0;
        TrafoSg02Status = 0;
        SwitchSg02Status = 0;
        Sg03Status = 0;
        TrafoSg03Status = 0;
        SwitchSg03Status = 0;
        Sg04Status = 0;
        TrafoSg04Status = 0;
        SwitchSg04Status = 0;
        BusA02Status = 0;
        BusA03Status = 0;
        SwitchSTAStatus = 0;
        SwitchSTBStatus = 0;
        TrafoAC01AStatus = 0;
        TrafoAC01BStatus = 0;
        SwitchAC01AStatus = 0;
        SwitchAC01BStatus = 0;
        BusC01AStatus = 0;
        BusC01BStatus = 0;
        SwitchSMAStatus = 0;
        SwitchSMBStatus = 0;
        SwitchSMCStatus = 0;
        SwitchSMDStatus = 0;
        K1Status = 0;
        K2Status = 0;
        K3Status = 0;
        K4Status = 0;
        SwitchC01Status = 0;
        condition_1 = 0;
        condition_2 = 0;
        condition_3 = 0;
        %sg01 variable
        sg01_power = 0;
        sg01_reactive = 0;
        sg01_active = 0;
        sg01_current = 0;
        sg01_voltage = 0;
        sg01_frequency = 50;

        %sg01 variable
        sg02_power = 0;
        sg02_reactive = 0;
        sg02_active = 0;
        sg02_current = 0;
        sg02_voltage = 0;
        sg02_frequency = 50;

        %sg01 variable
        sg03_power = 0;
        sg03_reactive = 0;
        sg03_active = 0;
        sg03_current = 0;
        sg03_voltage = 0;
        sg03_frequency = 50;

        %sg01 variable
        gt01_power = 0;
        gt01_reactive = 0;
        gt01_active = 0;
        gt01_current = 0;
        gt01_voltage = 0;
        gt01_frequency = 50;

        %TRAC01A variable
        trac01a_power = 0;
        trac01a_reactive = 0;
        trac01a_current = 0;
        trac01a_voltage = 0;

        %TRAC01B variable
        trac01b_power = 0;
        trac01b_reactive = 0;
        trac01b_current = 0;
        trac01b_voltage = 0;

        %C01A variable
        c01a_frequency = 50;
        c01a_voltage = 0;

        %C01B variable
        c01b_frequency = 50;
        c01b_voltage = 0;

        %instance
        requirement;
    end
    
    methods (Access = private)
        
        %%Image Change
        %Image change SG01
        function statusSg01Check(app)
            if app.Sg01Status == 1
                app.Sg01Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg01Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg01Check(app)
            if app.TrafoSg01Status == 1
                app.TrafoSg01Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg01Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg01Check(app)
            if app.SwitchSg01Status == 1
                app.SwitchSg01Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg01Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image change SG02    
        function statusSg02Check(app)
            if app.Sg02Status == 1
                app.Sg02Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg02Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg02Check(app)
            if app.TrafoSg02Status == 1
                app.TrafoSg02Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg02Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg02Check(app)
            if app.SwitchSg02Status == 1
                app.SwitchSg02Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg02Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image change SG03    
        function statusSg03Check(app)
            if app.Sg03Status == 1
                app.Sg03Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg03Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg03Check(app)
            if app.TrafoSg03Status == 1
                app.TrafoSg03Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg03Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg03Check(app)
            if app.SwitchSg03Status == 1
                app.SwitchSg03Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg03Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image change SG04    
        function statusSg04Check(app)
            if app.Sg04Status == 1
                app.Sg04Img.ImageSource = 'Assets/Generator - Run.png';
            else
                app.Sg04Img.ImageSource = 'Assets/Generator - Stop.png';
            end
        end
        function statusTrafoSg04Check(app)
            if app.TrafoSg04Status == 1
                app.TrafoSg04Img.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoSg04Img.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchSg04Check(app)
            if app.SwitchSg04Status == 1
                app.SwitchSg04Img.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSg04Img.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        %Image Change Bus A02
        function statusBusA02Check(app)
            if app.BusA02Status == 1
                app.BusA02Img.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusA02Img.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change Bus A03
        function statusBusA03Check(app)
            if app.BusA03Status == 1
                app.BusA03Img.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusA03Img.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change AC01A
        function statusSwitchSTACheck(app)
            if app.SwitchSTAStatus == 1
                app.SwitchSTAImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSTAImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusTrafoAC01ACheck(app)
            if app.TrafoAC01AStatus == 1
                app.TrafoAC01AImg.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoAC01AImg.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchAC01ACheck(app)
            if app.SwitchAC01AStatus == 1
                app.SwitchAC01AImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchAC01AImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image Change AC01B
        function statusSwitchSTBCheck(app)
            if app.SwitchSTBStatus == 1
                app.SwitchSTBImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSTBImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusTrafoAC01BCheck(app)
            if app.TrafoAC01BStatus == 1
                app.TrafoAC01BImg.ImageSource = 'Assets/Transformer Energize.png';
            else
                app.TrafoAC01BImg.ImageSource = 'Assets/Transformer De-Energize.png';
            end
        end
        function statusSwitchAC01BCheck(app)
            if app.SwitchAC01BStatus == 1
                app.SwitchAC01BImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchAC01BImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end

        %Image Change Bus C01A
        function statusBusC01ACheck(app)
            if app.BusC01AStatus == 1
                app.BusC01AImg.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusC01AImg.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change Bus C01B
        function statusBusC01BCheck(app)
            if app.BusC01BStatus == 1
                app.BusC01BImg.ImageSource = 'Assets/Bus Energize.png';
            else
                app.BusC01BImg.ImageSource = 'Assets/Bus De-Energize.png';
            end
        end
        %Image Change K3
        function statusSwitchSMACheck(app)
            if app.SwitchSMAStatus == 1
                app.SwitchSMAImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMAImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
            
        end
        function statusk3Check(app)
            if app.K3Status == 1
                app.k3Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k3Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end

        %Image Change K1
        function statusSwitchSMBCheck(app)
            if app.SwitchSMBStatus == 1
                app.SwitchSMBImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMBImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk1Check(app)
            if app.K1Status == 1
                app.k1Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k1Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end
        
        %Image Change K2
        function statusSwitchSMCCheck(app)
            if app.SwitchSMCStatus == 1
                app.SwitchSMCImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMCImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk2Check(app)
            if app.K2Status == 1
                app.k2Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k2Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end

        %Image Change K4
        function statusSwitchSMDCheck(app)
            if app.SwitchSMDStatus == 1
                app.SwitchSMDImg.ImageSource = 'Assets/380 Switch - Close (In Service).png';
            else
                app.SwitchSMDImg.ImageSource = 'Assets/380 Switch - Open (In Service).png';
            end
        end
        function statusk4Check(app)
            if app.K4Status == 1
                app.k4Img.ImageSource = 'Assets/Motor - Run.png';
            else
                app.k4Img.ImageSource = 'Assets/Motor - Stop.png';
            end
        end
        function statusSwitchC01Check(app)
            if app.SwitchC01Status == 1
                app.SwitchC01Img.ImageSource = 'Assets/380 Switch - Close (In Service) - 90.png';
            else
                app.SwitchC01Img.ImageSource = 'Assets/380 Switch - Open (In Service) - 90.png';
            end
        end
        %%

        %%status Change
        %Change status SG01
        function changeSg01Status(app)
            if app.Sg01Status == 0
                app.Sg01Status = 1;
            else
                app.Sg01Status = 0;
            end
            statusSg01Check(app);
        end
        function changeTrafoSg01Status(app)
            if app.TrafoSg01Status == 0
                app.TrafoSg01Status = 1;
            else
                app.TrafoSg01Status = 0;
            end
            statusTrafoSg01Check(app);
        end
        function changeSwitchSg01Status(app)
            if app.SwitchSg01Status == 0
                app.SwitchSg01Status = 1;
            else
                app.SwitchSg01Status = 0;
            end
            statusSwitchSg01Check(app);
        end

        %Change status SG02
        function changeSg02Status(app)
            if app.Sg02Status == 0
                app.Sg02Status = 1;
            else
                app.Sg02Status = 0;
            end
            statusSg02Check(app);
        end
        function changeTrafoSg02Status(app)
            if app.TrafoSg02Status == 0
                app.TrafoSg02Status = 1;
            else
                app.TrafoSg02Status = 0;
            end
            statusTrafoSg02Check(app);
        end
        function changeSwitchSg02Status(app)
            if app.SwitchSg02Status == 0
                app.SwitchSg02Status = 1;
            else
                app.SwitchSg02Status = 0;
            end
            statusSwitchSg02Check(app);
        end

        %Change status SG03
        function changeSg03Status(app)
            if app.Sg03Status == 0
                app.Sg03Status = 1;
            else
                app.Sg03Status = 0;
            end
            statusSg03Check(app);
        end
        function changeTrafoSg03Status(app)
            if app.TrafoSg03Status == 0
                app.TrafoSg03Status = 1;
            else
                app.TrafoSg03Status = 0;
            end
            statusTrafoSg03Check(app);
        end
        function changeSwitchSg03Status(app)
            if app.SwitchSg03Status == 0
                app.SwitchSg03Status = 1;
            else
                app.SwitchSg03Status = 0;
            end
            statusSwitchSg03Check(app);
        end

        %Change status SG04
        function changeSg04Status(app)
            if app.Sg04Status == 0
                app.Sg04Status = 1;
            else
                app.Sg04Status = 0;
            end
            statusSg04Check(app);
        end
        function changeTrafoSg04Status(app)
            if app.TrafoSg04Status == 0
                app.TrafoSg04Status = 1;
            else
                app.TrafoSg04Status = 0;
            end
            statusTrafoSg04Check(app);
        end
        function changeSwitchSg04Status(app)
            if app.SwitchSg04Status == 0
                app.SwitchSg04Status = 1;
            else
                app.SwitchSg04Status = 0;
            end
            statusSwitchSg04Check(app);
            
        end
        
        %Change status Bus A02
        function changeBusA02Status(app)
            if app.BusA02Status == 0
                app.BusA02Status = 1;
            else
                app.BusA02Status = 0;
            end
        end
        %Change status Bus A03
        function changeBusA03Status(app)
            if app.BusA03Status == 0
                app.BusA03Status = 1;
            else
                app.BusA03Status = 0;
            end
        end

        %Change status AC01A
        function changeSwitchSTAStatus(app)
            if app.SwitchSTAStatus == 0
                app.SwitchSTAStatus = 1;
            else
                app.SwitchSTAStatus = 0;
            end
            statusSwitchSTACheck(app);
        end
        function changeTrafoAC01AStatus(app)
            if app.TrafoAC01AStatus == 0
                app.TrafoAC01AStatus = 1;
            else
                app.TrafoAC01AStatus = 0;
            end
            statusTrafoAC01ACheck(app);
        end
        function changeSwitchAC01AStatus(app)
            if app.SwitchAC01AStatus == 0
                app.SwitchAC01AStatus = 1;
            else
                app.SwitchAC01AStatus = 0;
            end
            statusSwitchAC01ACheck(app);
        end

        %Change status AC01B
        function changeSwitchSTBStatus(app)
            if app.SwitchSTBStatus == 0
                app.SwitchSTBStatus = 1;
            else
                app.SwitchSTBStatus = 0;
            end
            statusSwitchSTBCheck(app);
        end
        function changeTrafoAC01BStatus(app)
            if app.TrafoAC01BStatus == 0
                app.TrafoAC01BStatus = 1;
            else
                app.TrafoAC01BStatus = 0;
            end
            statusTrafoAC01BCheck(app);
        end
        function changeSwitchAC01BStatus(app)
            if app.SwitchAC01BStatus == 0
                app.SwitchAC01BStatus = 1;
            else
                app.SwitchAC01BStatus = 0;
            end
        end
        
        %Change status Bus C01A
        function changeBusC01AStatus(app)
            if app.BusC01AStatus == 0
                app.BusC01AStatus = 1;
            else
                app.BusC01AStatus = 0;
            end
        end
        %Change status Bus C01B
        function changeBusC01BStatus(app)
            if app.BusC01BStatus == 0
                app.BusC01BStatus = 1;
            else
                app.BusC01BStatus = 0;
            end
        end
        
        %Change status Motor K3
        function changeSwitchSMAStatus(app)
            if app.SwitchSMAStatus == 0
                app.SwitchSMAStatus = 1;
            else
                app.SwitchSMAStatus = 0;
            end
            statusSwitchSMACheck(app);
        end
        function changek3Status(app)
            if app.K3Status == 0
                app.K3Status = 1;
            else
                app.K3Status = 0;
            end
            statusk3Check(app);
        end
        %Change status Motor K1
        function changeSwitchSMBStatus(app)
            if app.SwitchSMBStatus == 0
                app.SwitchSMBStatus = 1;
            else
                app.SwitchSMBStatus = 0;
            end
            statusSwitchSMBCheck(app);
        end
        function changek1Status(app)
            if app.K1Status == 0
                app.K1Status = 1;
            else
                app.K1Status = 0;
            end
            statusk1Check(app);
        end
        %Change status Motor K2
        function changeSwitchSMCStatus(app)
            if app.SwitchSMCStatus == 0
                app.SwitchSMCStatus = 1;
            else
                app.SwitchSMCStatus = 0;
            end
            statusSwitchSMCCheck(app);
        end
        function changek2Status(app)
            if app.K2Status == 0
                app.K2Status = 1;
            else
                app.K2Status = 0;
            end
            statusk2Check(app);
        end
        %Change status Motor K4
        function changeSwitchSMDStatus(app)
            if app.SwitchSMDStatus == 0
                app.SwitchSMDStatus = 1;
            else
                app.SwitchSMDStatus = 0;
            end
            statusSwitchSMDCheck(app);
        end
        function changek4Status(app)
            if app.K4Status == 0
                app.K4Status = 1;
            else
                app.K4Status = 0;
            end
            statusk4Check(app);
        end
        
        function changeSwitchC01Status(app)
            if app.SwitchC01Status == 0
                app.SwitchC01Status = 1;
            else
                app.SwitchC01Status = 0;
            end
            statusSwitchC01Check(app);
        end

        function changeSg01Value(app)
            app.powerSg01Value.Text = int2str(app.sg01_power);
            app.activeSg01Value.Text = int2str(app.sg01_active);
            app.reactiveSg01Value.Text = int2str(app.sg01_reactive);
            app.currentSg01Value.Text = int2str(app.sg01_current);
            app.voltageSg01Value.Text = int2str(app.sg01_voltage);
            app.frequencySg01Value.Text = int2str(app.sg01_frequency);
        end

        function changeSg02Value(app)
            app.powerSg02Value.Text = int2str(app.sg02_power);
            app.activeSg02Value.Text = int2str(app.sg02_active);
            app.reactiveSg02Value.Text = int2str(app.sg02_reactive);
            app.currentSg02Value.Text = int2str(app.sg02_current);
            app.voltageSg02Value.Text = int2str(app.sg02_voltage);
            app.frequencySg02Value.Text = int2str(app.sg02_frequency);
        end

        function changeSg03Value(app)
            app.powerSg03Value.Text = int2str(app.sg03_power);
            app.activeSg03Value.Text = int2str(app.sg03_active);
            app.reactiveSg03Value.Text = int2str(app.sg03_reactive);
            app.currentSg03Value.Text = int2str(app.sg03_current);
            app.voltageSg03Value.Text = int2str(app.sg03_voltage);
            app.frequencySg03Value.Text = int2str(app.sg03_frequency);
        end

        function changeGt01Value(app)
            app.powerGt01Value.Text = int2str(app.gt01_power);
            app.activeGt01Value.Text = int2str(app.gt01_active);
            app.reactiveGt01Value.Text = int2str(app.gt01_reactive);
            app.currentGt01Value.Text = int2str(app.gt01_current);
            app.voltageGt01Value.Text = int2str(app.gt01_voltage);
            app.frequencyGt01Value.Text = int2str(app.gt01_frequency);
        end

        
        %%
        %Logic
        
        %condition change
        function change_condition_state(app)
            if app.condition_1 == 1
                app.condition_1_label.BackgroundColor = 'g';
            else
                app.condition_1_label.BackgroundColor = 'r';
            end

            if app.condition_2 == 1
                app.condition_2_label.BackgroundColor = 'g';
            else
                app.condition_2_label.BackgroundColor = 'r';
            end

            if app.condition_3 == 1
                app.condition_3_label.BackgroundColor = 'g';
            else
                app.condition_3_label.BackgroundColor = 'r';
            end
        end
        %Bus Bar A02
        function busA02Energize(app)
            if (app.Sg01Status == 1 && app.SwitchSg01Status == 1) || (app.Sg02Status == 1 && app.SwitchSg02Status ==1)
               app.BusA02Status = 1;
            else
               app.BusA02Status = 0;
            end
            
            statusBusA02Check(app);
        end
        %Bus Bar A03
        function busA03Energize(app)
            if (app.Sg03Status == 1 && app.SwitchSg03Status == 1) || (app.Sg04Status == 1 && app.SwitchSg04Status ==1)
               app.BusA03Status = 1;
            else
               app.BusA03Status = 0;
            end
            
            statusBusA03Check(app);
        end

        %Bus Bar C01A
        function busC01AEnergize(app)
            if (app.SwitchSTAStatus == 1 && app.BusA02Status == 1 && app.SwitchAC01AStatus == 1) 
               app.BusC01AStatus = 1;
            else
               app.BusC01AStatus = 0;
            end
            
            statusBusC01ACheck(app);
        end

        %Bus Bar C01B
        function busC01BEnergize(app)
            if (app.SwitchSTBStatus == 1 && app.BusA03Status == 1 && app.SwitchAC01BStatus == 1) 
               app.BusC01BStatus = 1;
            else
               app.BusC01BStatus = 0;
            end
            
            statusBusC01BCheck(app);
        end

        %Trafo AC01A
        function trafoAC01AEnergize(app)
            if (app.SwitchSTAStatus == 1 && app.BusA02Status == 1) 
               app.TrafoAC01AStatus = 1;
            else
               app.TrafoAC01AStatus = 0;
            end
            
            statusTrafoAC01ACheck(app);
        end

        %Trafo AC01B
        function trafoAC01BEnergize(app)
            if (app.SwitchSTBStatus == 1 && app.BusA03Status == 1) 
               app.TrafoAC01BStatus = 1;
            else
               app.TrafoAC01BStatus = 0;
            end
            
            statusTrafoAC01BCheck(app);
        end

        %Motor K3
        function motorK3Run(app)
            if (app.BusC01AStatus == 1 || app.BusC01BStatus == 1) && app.SwitchSMAStatus == 1
               app.K3Status = 1;
            else
               app.K3Status = 0;
            end
            
            statusk3Check(app);
        end

        %Motor K1
        function motorK1Run(app)
            if (app.BusC01AStatus == 1 || app.BusC01BStatus == 1) && app.SwitchSMBStatus == 1
               app.K1Status = 1;
            else
               app.K1Status = 0;
            end
            
            statusk1Check(app);
        end

        %Motor K4
        function motorK2Run(app)
            if (app.BusC01AStatus == 1 || app.BusC01BStatus == 1) && app.SwitchSMCStatus == 1
               app.K2Status = 1;
            else
               app.K2Status = 0;
            end
            
            statusk2Check(app);
        end

        %Motor K2
        function motorK4Run(app)
            if (app.BusC01AStatus == 1 || app.BusC01BStatus == 1) && app.SwitchSMDStatus == 1
               app.K4Status = 1;
            else
               app.K4Status = 0;
            end
            
            statusk4Check(app);
        end
        function busC01Distributed(app)
            if app.SwitchC01Status==1 && app.BusC01AStatus == 1 && app.BusC01BStatus == 0
                
                app.BusC01BStatus = 1;
            elseif app.SwitchC01Status==1 && app.BusC01AStatus == 0 && app.BusC01BStatus == 1
                app.BusC01AStatus =1;
            end
            statusBusC01ACheck(app);
            statusBusC01BCheck(app);
        end
    end
    
    methods (Access = public)
        
        function importdata(app,condition_1,condition_2,condition_3)
            app.condition_1 = condition_1;
            app.condition_2 = condition_2;
            app.condition_3 = condition_3;
            change_condition_state(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartStopSG01Button
        function StartStopSG01ButtonPushed(app, event)
            changeSg01Status(app);
            changeTrafoSg01Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
        end

        % Button pushed function: StartStopSG02Button
        function StartStopSG02ButtonPushed(app, event)
            changeSg02Status(app);
            changeTrafoSg02Status(app);
            busA02Energize(app);
            busC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
        end

        % Button pushed function: StartStopSG03Button
        function StartStopSG03ButtonPushed(app, event)
            changeSg03Status(app);
            changeTrafoSg03Status(app);
            busA03Energize(app);
            busC01BEnergize(app)
            trafoAC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: StartStopGT01Button
        function StartStopGT01ButtonPushed(app, event)
            changeSg04Status(app);
            changeTrafoSg04Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG01Button
        function OpenCloseSwitchSG01ButtonPushed(app, event)
            changeSwitchSg01Status(app);
            
            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG02Button
        function OpenCloseSwitchSG02ButtonPushed(app, event)
            changeSwitchSg02Status(app);

            busA02Energize(app);
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
        end

        % Button pushed function: OpenCloseSwitchSG03Button
        function OpenCloseSwitchSG03ButtonPushed(app, event)
            changeSwitchSg03Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSwitchGT01Button
        function OpenCloseSwitchGT01ButtonPushed(app, event)
            changeSwitchSg04Status(app);
            busA03Energize(app);
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSTAButton
        function OpenCloseSTAButtonPushed(app, event)
            changeSwitchSTAStatus(app);
            
            busC01AEnergize(app);
            trafoAC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
            
        end

        % Button pushed function: OpenCloseSTBButton
        function OpenCloseSTBButtonPushed(app, event)
            changeSwitchSTBStatus(app);
            
            busC01BEnergize(app);
            trafoAC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSwAC01AButton
        function OpenCloseSwAC01AButtonPushed(app, event)
            changeSwitchAC01AStatus(app);
            busC01AEnergize(app);
            motorK3Run(app);
            motorK1Run(app);
        end

        % Button pushed function: OpenCloseSwAC01BButton
        function OpenCloseSwAC01BButtonPushed(app, event)
            changeSwitchAC01BStatus(app);
            statusSwitchAC01BCheck(app);
            busC01BEnergize(app);
            motorK4Run(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSMAButton
        function OpenCloseSMAButtonPushed(app, event)
            changeSwitchSMAStatus(app);
            motorK3Run(app);
        end

        % Button pushed function: OpenCloseSMBButton
        function OpenCloseSMBButtonPushed(app, event)
            changeSwitchSMBStatus(app);
            motorK1Run(app);
        end

        % Button pushed function: OpenCloseSMCButton
        function OpenCloseSMCButtonPushed(app, event)
            changeSwitchSMCStatus(app);
            motorK2Run(app);
        end

        % Button pushed function: OpenCloseSMDButton
        function OpenCloseSMDButtonPushed(app, event)
            changeSwitchSMDStatus(app);
            motorK4Run(app);
        end

        % Button pushed function: OpenCloseSwitchC01Button
        function OpenCloseSwitchC01ButtonPushed(app, event)
            changeSwitchC01Status(app);
            busC01Distributed(app);
        end

        % Button pushed function: ForceConditionButton
        function ForceConditionButtonPushed(app, event)
            app.requirement = requirement(app);     %#ok 
            importdata(app.requirement,app.condition_1,app.condition_2,app.condition_3); %#ok 
        end

        % Button pushed function: StartPowerFlowButton
        function StartPowerFlowButtonPushed(app, event)
            changeSg01Value(app);
            changeSg02Value(app);
            changeSg03Value(app);
            changeGt01Value(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Assets', 'New Design.png');
            app.OpenCloseSwitchC01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchC01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchC01ButtonPushed, true);
            app.OpenCloseSwitchC01Button.Text = {'Open/Close'; 'Switch C01'};
            app.OpenCloseSMDButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMDButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMDButtonPushed, true);
            app.OpenCloseSMDButton.Text = {'Open/Close'; 'SMD'};
            app.OpenCloseSMCButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMCButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMCButtonPushed, true);
            app.OpenCloseSMCButton.Text = {'Open/Close'; 'SMC'};
            app.OpenCloseSMBButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMBButtonPushed, true);
            app.OpenCloseSMBButton.Text = {'Open/Close'; 'SMB'};
            app.OpenCloseSMAButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSMAButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSMAButtonPushed, true);
            app.OpenCloseSMAButton.Text = {'Open/Close'; 'SMA'};
            app.OpenCloseSwAC01AButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwAC01AButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwAC01AButtonPushed, true);
            app.OpenCloseSwAC01AButton.Text = {'Open/Close'; 'Sw. AC01A'};
            app.OpenCloseSwAC01BButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwAC01BButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwAC01BButtonPushed, true);
            app.OpenCloseSwAC01BButton.Text = {'Open/Close'; 'Sw. AC01B'};
            app.OpenCloseSTBButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSTBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSTBButtonPushed, true);
            app.OpenCloseSTBButton.Text = {'Open/Close'; 'STB'};
            app.OpenCloseSTAButton = uibutton(app.UIFigure, 'push');
            app.OpenCloseSTAButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSTAButtonPushed, true);
            app.OpenCloseSTAButton.Text = {'Open/Close'; 'STA'};
            app.OpenCloseSwitchGT01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchGT01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchGT01ButtonPushed, true);
            app.OpenCloseSwitchGT01Button.Text = {'Open/Close'; 'Switch GT01'};
            app.OpenCloseSwitchSG03Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG03Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG03ButtonPushed, true);
            app.OpenCloseSwitchSG03Button.Text = {'Open/Close'; 'Switch SG03'};
            app.OpenCloseSwitchSG02Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG02Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG02ButtonPushed, true);
            app.OpenCloseSwitchSG02Button.Text = {'Open/Close'; 'Switch SG02'};
            app.OpenCloseSwitchSG01Button = uibutton(app.UIFigure, 'push');
            app.OpenCloseSwitchSG01Button.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseSwitchSG01ButtonPushed, true);
            app.OpenCloseSwitchSG01Button.Text = {'Open/Close'; 'Switch SG01'};
            app.StartStopGT01Button = uibutton(app.UIFigure, 'push');
            app.StartStopGT01Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopGT01ButtonPushed, true);
            app.StartStopGT01Button.Text = {'Start/Stop'; 'GT01'};
            app.StartStopSG03Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG03Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG03ButtonPushed, true);
            app.StartStopSG03Button.Text = {'Start/Stop'; 'SG03'};
            app.StartStopSG02Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG02Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG02ButtonPushed, true);
            app.StartStopSG02Button.Text = {'Start/Stop'; 'SG02'};
            app.StartStopSG01Button = uibutton(app.UIFigure, 'push');
            app.StartStopSG01Button.ButtonPushedFcn = createCallbackFcn(app, @StartStopSG01ButtonPushed, true);
            app.StartStopSG01Button.Text = {'Start/Stop'; 'SG01'};
            app.SwitchC01Img = uiimage(app.UIFigure);
            app.SwitchC01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service) - 90.png');
            app.k4Img = uiimage(app.UIFigure);
            app.k4Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k3Img = uiimage(app.UIFigure);
            app.k3Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k2Img = uiimage(app.UIFigure);
            app.k2Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.k1Img = uiimage(app.UIFigure);
            app.k1Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Motor - Comm. Loss.png');
            app.SwitchSMDImg = uiimage(app.UIFigure);
            app.SwitchSMDImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMCImg = uiimage(app.UIFigure);
            app.SwitchSMCImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMBImg = uiimage(app.UIFigure);
            app.SwitchSMBImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSMAImg = uiimage(app.UIFigure);
            app.SwitchSMAImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.BusC01BImg = uiimage(app.UIFigure);
            app.BusC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.BusC01AImg = uiimage(app.UIFigure);
            app.BusC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.SwitchAC01BImg = uiimage(app.UIFigure);
            app.SwitchAC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchAC01AImg = uiimage(app.UIFigure);
            app.SwitchAC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.TrafoAC01BImg = uiimage(app.UIFigure);
            app.TrafoAC01BImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoAC01AImg = uiimage(app.UIFigure);
            app.TrafoAC01AImg.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.SwitchSTBImg = uiimage(app.UIFigure);
            app.SwitchSTBImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSTAImg = uiimage(app.UIFigure);
            app.SwitchSTAImg.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.BusA03Img = uiimage(app.UIFigure);
            app.BusA03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.BusA02Img = uiimage(app.UIFigure);
            app.BusA02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Bus Standby.png');
            app.SwitchSg04Img = uiimage(app.UIFigure);
            app.SwitchSg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg03Img = uiimage(app.UIFigure);
            app.SwitchSg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg02Img = uiimage(app.UIFigure);
            app.SwitchSg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.SwitchSg01Img = uiimage(app.UIFigure);
            app.SwitchSg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', '380 Switch - Comm. Loss (In Service).png');
            app.TrafoSg04Img = uiimage(app.UIFigure);
            app.TrafoSg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg03Img = uiimage(app.UIFigure);
            app.TrafoSg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg02Img = uiimage(app.UIFigure);
            app.TrafoSg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.TrafoSg01Img = uiimage(app.UIFigure);
            app.TrafoSg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Transformer Comm. Loss.png');
            app.Sg04Img = uiimage(app.UIFigure);
            app.Sg04Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg03Img = uiimage(app.UIFigure);
            app.Sg03Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg02Img = uiimage(app.UIFigure);
            app.Sg02Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.Sg01Img = uiimage(app.UIFigure);
            app.Sg01Img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator - Comm. Loss.png');
            app.StartPowerFlowButton = uibutton(app.UIFigure, 'push');
            app.StartPowerFlowButton.ButtonPushedFcn = createCallbackFcn(app, @StartPowerFlowButtonPushed, true);
            app.StartPowerFlowButton.Text = {'Start'; 'Power Flow'};
            app.OPCButton = uibutton(app.UIFigure, 'push');
            app.OPCButton.Text = 'OPC';
            app.SimulationOnLineButton = uibutton(app.UIFigure, 'push');
            app.SimulationOnLineButton.Text = {'Simulation/'; 'On-Line'};
            app.ForceConditionButton = uibutton(app.UIFigure, 'push');
            app.ForceConditionButton.ButtonPushedFcn = createCallbackFcn(app, @ForceConditionButtonPushed, true);
            app.ForceConditionButton.Text = {'Force'; 'Condition'};
            app.condition_3_label = uilabel(app.UIFigure);
            app.condition_3_label.Text = 'UNIT AUXILIARIES READY FOR START';
            app.condition_2_label = uilabel(app.UIFigure);
            app.condition_2_label.Text = 'UNIT BREAKING SYSTEM STATUS IS READY';
            app.condition_1_label = uilabel(app.UIFigure);
            app.condition_1_label.Text = 'GENERATOR BRAKES POSITION IS OFF';
            app.frequencySg01Unit = uilabel(app.UIFigure);
            app.frequencySg01Unit.Text = 'Hz';
            app.frequencySg01Value = uilabel(app.UIFigure);
            app.frequencySg01Value.Text = '******';
            app.voltageSg01Unit = uilabel(app.UIFigure);
            app.voltageSg01Unit.Text = 'kV';
            app.voltageSg01Value = uilabel(app.UIFigure);
            app.voltageSg01Value.Text = '******';
            app.currentSg01Unit = uilabel(app.UIFigure);
            app.currentSg01Unit.Text = 'A';
            app.currentSg01Value = uilabel(app.UIFigure);
            app.currentSg01Value.Text = '******';
            app.activeSg01Unit = uilabel(app.UIFigure);
            app.activeSg01Unit.Text = 'kVA';
            app.activeSg01Value = uilabel(app.UIFigure);
            app.activeSg01Value.Text = '******';
            app.reactiveSg01Unit = uilabel(app.UIFigure);
            app.reactiveSg01Unit.Text = 'kVAr';
            app.reactiveSg01Value = uilabel(app.UIFigure);
            app.reactiveSg01Value.Text = '******';
            app.powerSg01Unit = uilabel(app.UIFigure);
            app.powerSg01Unit.Text = 'kW';
            app.powerSg01Value = uilabel(app.UIFigure);
            app.powerSg01Value.Text = '******';
            app.powerSg02Value = uilabel(app.UIFigure);
            app.powerSg02Value.Text = '******';
            app.powerSg02Unit = uilabel(app.UIFigure);
            app.powerSg02Unit.Text = 'kW';
            app.reactiveSg02Value = uilabel(app.UIFigure);
            app.reactiveSg02Value.Text = '******';
            app.reactiveSg02Unit = uilabel(app.UIFigure);
            app.reactiveSg02Unit.Text = 'kVAr';
            app.activeSg02Value = uilabel(app.UIFigure);
            app.activeSg02Value.Text = '******';
            app.activeSg02Unit = uilabel(app.UIFigure);
            app.activeSg02Unit.Text = 'kVA';
            app.currentSg02Value = uilabel(app.UIFigure);
            app.currentSg02Value.Text = '******';
            app.currentSg02Unit = uilabel(app.UIFigure);
            app.currentSg02Unit.Text = 'A';
            app.voltageSg02Value = uilabel(app.UIFigure);
            app.voltageSg02Value.Text = '******';
            app.voltageSg02Unit = uilabel(app.UIFigure);
            app.voltageSg02Unit.Text = 'kV';
            app.frequencySg02Value = uilabel(app.UIFigure);
            app.frequencySg02Value.Text = '******';
            app.frequencySg02Unit = uilabel(app.UIFigure);
            app.frequencySg02Unit.Text = 'Hz';
            app.frequencySg03Unit = uilabel(app.UIFigure);
            app.frequencySg03Unit.Text = 'Hz';
            app.frequencySg03Value = uilabel(app.UIFigure);
            app.frequencySg03Value.Text = '******';
            app.voltageSg03Unit = uilabel(app.UIFigure);
            app.voltageSg03Unit.Text = 'kV';
            app.voltageSg03Value = uilabel(app.UIFigure);
            app.voltageSg03Value.Text = '******';
            app.currentSg03Unit = uilabel(app.UIFigure);
            app.currentSg03Unit.Text = 'A';
            app.currentSg03Value = uilabel(app.UIFigure);
            app.currentSg03Value.Text = '******';
            app.activeSg03Unit = uilabel(app.UIFigure);
            app.activeSg03Unit.Text = 'kVA';
            app.activeSg03Value = uilabel(app.UIFigure);
            app.activeSg03Value.Text = '******';
            app.reactiveSg03Unit = uilabel(app.UIFigure);
            app.reactiveSg03Unit.Text = 'kVAr';
            app.reactiveSg03Value = uilabel(app.UIFigure);
            app.reactiveSg03Value.Text = '******';
            app.powerSg03Unit = uilabel(app.UIFigure);
            app.powerSg03Unit.Text = 'kW';
            app.powerSg03Value = uilabel(app.UIFigure);
            app.powerSg03Value.Text = '******';
            app.frequencyGt01Unit = uilabel(app.UIFigure);
            app.frequencyGt01Unit.Text = 'Hz';
            app.powerGt01Value = uilabel(app.UIFigure);
            app.powerGt01Value.Text = '******';
            app.powerGt01Unit = uilabel(app.UIFigure);
            app.powerGt01Unit.Text = 'kW';
            app.reactiveGt01Value = uilabel(app.UIFigure);
            app.reactiveGt01Value.Text = '******';
            app.reactiveGt01Unit = uilabel(app.UIFigure);
            app.reactiveGt01Unit.Text = 'kVAr';
            app.activeGt01Value = uilabel(app.UIFigure);
            app.activeGt01Value.Text = '******';
            app.activeGt01Unit = uilabel(app.UIFigure);
            app.activeGt01Unit.Text = 'kVA';
            app.currentGt01Value = uilabel(app.UIFigure);
            app.currentGt01Value.Text = '******';
            app.currentGt01Unit = uilabel(app.UIFigure);
            app.currentGt01Unit.Text = 'A';
            app.voltageGt01Value = uilabel(app.UIFigure);
            app.voltageGt01Value.Text = '******';
            app.voltageGt01Unit = uilabel(app.UIFigure);
            app.voltageGt01Unit.Text = 'kV';
            app.frequencyGt01Value = uilabel(app.UIFigure);
            app.frequencyGt01Value.Text = '******';
            app.voltagetTrAc01AUnit = uilabel(app.UIFigure);
            app.voltagetTrAc01AUnit.Text = 'kV';
            app.voltageTrAc01AValue = uilabel(app.UIFigure);
            app.voltageTrAc01AValue.Text = '******';
            app.currentTrAc01AUnit = uilabel(app.UIFigure);
            app.currentTrAc01AUnit.Text = 'A';
            app.currentTrAc01AValue = uilabel(app.UIFigure);
            app.currentTrAc01AValue.Text = '******';
            app.reactiveTrAc01AUnit = uilabel(app.UIFigure);
            app.reactiveTrAc01AUnit.Text = 'kVAr';
            app.reactiveTrAc01AValue = uilabel(app.UIFigure);
            app.reactiveTrAc01AValue.Text = '******';
            app.powerTrAc01AUnit = uilabel(app.UIFigure);
            app.powerTrAc01AUnit.Text = 'kW';
            app.powerTrAc01AValue = uilabel(app.UIFigure);
            app.powerTrAc01AValue.Text = '******';
            app.voltagetTrAc01BUnit = uilabel(app.UIFigure);
            app.voltagetTrAc01BUnit.Text = 'kV';
            app.voltageTrAc01BValue = uilabel(app.UIFigure);
            app.voltageTrAc01BValue.Text = '******';
            app.currentTrAc01BUnit = uilabel(app.UIFigure);
            app.currentTrAc01BUnit.Text = 'A';
            app.currentTrAc01BValue = uilabel(app.UIFigure);
            app.currentTrAc01BValue.Text = '******';
            app.reactiveTrAc01BUnit = uilabel(app.UIFigure);
            app.reactiveTrAc01BUnit.Text = 'kVAr';
            app.reactiveTrAc01BValue = uilabel(app.UIFigure);
            app.reactiveTrAc01BValue.Text = '******';
            app.powerTrAc01BUnit = uilabel(app.UIFigure);
            app.powerTrAc01BUnit.Text = 'kW';
            app.powerTrAc01BValue = uilabel(app.UIFigure);
            app.powerTrAc01BValue.Text = '******';
            app.frequencyC01AUnit = uilabel(app.UIFigure);
            app.frequencyC01AUnit.Text = 'Hz';
            app.frequencyC01AValue = uilabel(app.UIFigure);
            app.frequencyC01AValue.Text = '******';
            app.voltageC01AUnit = uilabel(app.UIFigure);
            app.voltageC01AUnit.Text = 'kV';
            app.voltageC01AValue = uilabel(app.UIFigure);
            app.voltageC01AValue.Text = '******';
            app.frequencyC01BUnit = uilabel(app.UIFigure);
            app.frequencyC01BUnit.Text = 'Hz';
            app.frequencyC01BValue = uilabel(app.UIFigure);
            app.frequencyC01BValue.Text = '******';
            app.voltageC01BUnit = uilabel(app.UIFigure);
            app.voltageC01BUnit.Text = 'kV';
            app.voltageC01BValue = uilabel(app.UIFigure);
            app.voltageC01BValue.Text = '******';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psathmi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end